classdef LPC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrowseInputAudioButton          matlab.ui.control.Button
        CompressInputAudioButton        matlab.ui.control.Button
        PlayandPlotInputAudioButton     matlab.ui.control.Button
        PlayandPlotPredictedAudioButton  matlab.ui.control.Button
        InputAudioSizeKBsEditFieldLabel  matlab.ui.control.Label
        InputAudioSizeKBsEditField      matlab.ui.control.EditField
        PredictedAudioSizeKBsEditFieldLabel  matlab.ui.control.Label
        PredictedAudioSizeKBsEditField  matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseInputAudioButton
        function BrowseInputAudioButtonPushed(app, event)
            global inputAudio;
            [filename, filepath] = uigetfile({'*.wav*'}, 'Browse Audio file');
            inputAudio = [filepath filename];
            handles.inputfile = inputAudio;
            guidata(figure,handles);
        end

        % Button pushed function: CompressInputAudioButton
        function CompressInputAudioButtonPushed(app, event)
            global inputAudio;
            global compressedAudio;
            compressedAudio = lpcCompress(inputAudio);
            compressedAudio = which(compressedAudio);
        end

        % Button pushed function: PlayandPlotInputAudioButton
        function PlayandPlotInputAudioButtonPushed(app, event)
            global inputAudio;
            [x, fs] = audioread(inputAudio);
            inputSound = audioplayer(x, fs);
            playblocking(inputSound);
            %t = linspace(0, length(x)/fs, length(x));
            t = 1:length(x);
            plot(app.UIAxes,t,x);
            info = audioinfo(inputAudio);
            samplingRate = info.SampleRate;
            sampleSize = info.BitsPerSample;
            duration = info.Duration;
            storage = samplingRate*sampleSize*duration;
            storage = storage/(8*1024);
            app.InputAudioSizeKBsEditField.Value = num2str(storage); 
        end

        % Button pushed function: PlayandPlotPredictedAudioButton
        function PlayandPlotPredictedAudioButtonPushed(app, event)
            global compressedAudio;
            [x, fs] = audioread(compressedAudio);
            fs = 8000;
            outputSound = audioplayer(x, fs);
            playblocking(outputSound);
            %t = linspace(0, length(x)/fs, length(x));
            t = 1:length(x);
            plot(app.UIAxes2,t,x);
            info = audioinfo(compressedAudio);
            samplingRate = info.SampleRate;
            sampleSize = info.BitsPerSample;
            duration = info.Duration;
            storage = samplingRate*sampleSize*duration;
            storage = storage/(8*1024);
            app.PredictedAudioSizeKBsEditField.Value = num2str(storage); 
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: InputAudioSizeKBsEditField
        function InputAudioSizeKBsEditFieldValueChanged(app, event)
            value = app.InputAudioSizeKBsEditField.Value;
        end

        % Value changed function: PredictedAudioSizeKBsEditField
        function PredictedAudioSizeKBsEditFieldValueChanged(app, event)
            value = app.PredictedAudioSizeKBsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseInputAudioButton = uibutton(app.UIFigure, 'push');
            app.BrowseInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputAudioButtonPushed, true);
            app.BrowseInputAudioButton.Text = 'Browse Input Audio';
            app.CompressInputAudioButton = uibutton(app.UIFigure, 'push');
            app.CompressInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CompressInputAudioButtonPushed, true);
            app.CompressInputAudioButton.Text = 'Compress Input Audio';
            app.PlayandPlotInputAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayandPlotInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayandPlotInputAudioButtonPushed, true);
            app.PlayandPlotInputAudioButton.Text = 'Play and Plot Input Audio';
            app.PlayandPlotPredictedAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayandPlotPredictedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayandPlotPredictedAudioButtonPushed, true);
            app.PlayandPlotPredictedAudioButton.Text = 'Play and Plot Predicted Audio';
            app.InputAudioSizeKBsEditFieldLabel = uilabel(app.UIFigure);
            app.InputAudioSizeKBsEditFieldLabel.Text = 'Input Audio Size (KBs)';
            app.InputAudioSizeKBsEditField = uieditfield(app.UIFigure, 'text');
            app.InputAudioSizeKBsEditField.ValueChangedFcn = createCallbackFcn(app, @InputAudioSizeKBsEditFieldValueChanged, true);
            app.PredictedAudioSizeKBsEditFieldLabel = uilabel(app.UIFigure);
            app.PredictedAudioSizeKBsEditFieldLabel.Text = 'Predicted Audio Size (KBs)';
            app.PredictedAudioSizeKBsEditField = uieditfield(app.UIFigure, 'text');
            app.PredictedAudioSizeKBsEditField.ValueChangedFcn = createCallbackFcn(app, @PredictedAudioSizeKBsEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Audio')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Input Audio Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Audio')
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Predicted Audio Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end